classdef Strada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        VideoAnalysisTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        Slider                          matlab.ui.control.Slider
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        GridLayout15                    matlab.ui.container.GridLayout
        FPSEditField                    matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        StartSegmentButton              matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        SegmentsPanel                   matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout35                    matlab.ui.container.GridLayout
        MonkeyEditField                 matlab.ui.control.EditField
        MonkeyEditFieldLabel            matlab.ui.control.Label
        RecEditField                    matlab.ui.control.EditField
        RecEditFieldLabel               matlab.ui.control.Label
        DayEditField                    matlab.ui.control.EditField
        DayEditFieldLabel               matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        ChangeButton                    matlab.ui.control.Button
        DIRLabel                        matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        SaveSegmentsButton              matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        LabelsPanel                     matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        selectButton_4                  matlab.ui.control.Button
        selectButton_3                  matlab.ui.control.Button
        selectButton_2                  matlab.ui.control.Button
        selectButton                    matlab.ui.control.Button
        EditField4                      matlab.ui.control.EditField
        EditField3                      matlab.ui.control.EditField
        EditField2                      matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        GridLayout5                     matlab.ui.container.GridLayout
        SelectVideoButton               matlab.ui.control.Button
        KinematicsTab                   matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        allposTab                       matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        AutoSegmentPanel                matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        msLabel_3                       matlab.ui.control.Label
        MaxReachPerturbationEditField   matlab.ui.control.NumericEditField
        MaxReachPerturbationEditFieldLabel  matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        unitsLabel_2                    matlab.ui.control.Label
        unitsLabel                      matlab.ui.control.Label
        MinTimeBWReachEditField         matlab.ui.control.NumericEditField
        MinReachCooldownLabel           matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        DurationmsLabel                 matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        BaselineMinDurationsEditField   matlab.ui.control.NumericEditField
        BaselineMinDurationsEditFieldLabel  matlab.ui.control.Label
        BaselineThresholdEditField      matlab.ui.control.NumericEditField
        BaselineThresholdEditFieldLabel  matlab.ui.control.Label
        UpperNormMagThresholdEditField  matlab.ui.control.NumericEditField
        UpperNormMagThresholdEditFieldLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        PositionMovAvgWindowEditField   matlab.ui.control.NumericEditField
        PositionMovAvgWindowEditFieldLabel  matlab.ui.control.Label
        AutoSegmentButton               matlab.ui.control.Button
        ReachThresholdEditField         matlab.ui.control.NumericEditField
        ReachThresholdEditFieldLabel    matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        Slider_2                        matlab.ui.control.RangeSlider
        VelocityPanel                   matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        UIAxesVel                       matlab.ui.control.UIAxes
        PositionPanel                   matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        UIAxesPos                       matlab.ui.control.UIAxes
        GridLayout20                    matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        ListBox                         matlab.ui.control.ListBox
        GridLayout18                    matlab.ui.container.GridLayout
        SelectDLCallposition3DmatfileforstudyLabel  matlab.ui.control.Label
        LoadDLCButton                   matlab.ui.control.Button
        nansTab                         matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        VelocitySwitch                  matlab.ui.control.Switch
        SavePos3DChangesButton          matlab.ui.control.Button
        GridLayout31                    matlab.ui.container.GridLayout
        earlystartEditField             matlab.ui.control.NumericEditField
        earlystartEditFieldLabel        matlab.ui.control.Label
        LoadSegmentsButton              matlab.ui.control.Button
        LogText                         matlab.ui.control.TextArea
        ListBox_2                       matlab.ui.control.ListBox
        TextArea_2                      matlab.ui.control.TextArea
        DetectButton                    matlab.ui.control.Button
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        GridLayout34                    matlab.ui.container.GridLayout
        resettooriginaldlcEscLabel      matlab.ui.control.Label
        RESETButton                     matlab.ui.control.Button
        GridLayout33_2                  matlab.ui.container.GridLayout
        Lamp                            matlab.ui.control.Lamp
        Segment_NumberLabel             matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        SmoothingSettingsPanel          matlab.ui.container.Panel
        GridLayout33_3                  matlab.ui.container.GridLayout
        sLabel                          matlab.ui.control.Label
        SmoothButton                    matlab.ui.control.Button
        smoothwindowmsEditField         matlab.ui.control.NumericEditField
        smoothwindowmsEditFieldLabel    matlab.ui.control.Label
        FixMissingValuesPanel           matlab.ui.container.Panel
        GridLayout33                    matlab.ui.container.GridLayout
        tLabel                          matlab.ui.control.Label
        ImputationMeanButton            matlab.ui.control.Button
        InterpolateButton               matlab.ui.control.Button
        ThresholdFactorEditField        matlab.ui.control.NumericEditField
        ThresholdFactorEditFieldLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        NeuralAnalysisTab               matlab.ui.container.Tab
        GridLayout40                    matlab.ui.container.GridLayout
        GridLayout42                    matlab.ui.container.GridLayout
        ElectrodePositionPanel          matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        uEcog244electrodesLabel         matlab.ui.control.Label
        LoadGooseButton                 matlab.ui.control.Button
        LoaduECoGSchemaButton           matlab.ui.control.Button
        GridLayout48                    matlab.ui.container.GridLayout
        SHOWButton                      matlab.ui.control.Button
        FilecontentsPanel               matlab.ui.container.Panel
        GridLayout47                    matlab.ui.container.GridLayout
        ListBox_3                       matlab.ui.control.ListBox
        SelectButton                    matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        LoadNeuralButton                matlab.ui.control.Button
        GridLayout41                    matlab.ui.container.GridLayout
        GridLayout43                    matlab.ui.container.GridLayout
        GridLayout50                    matlab.ui.container.GridLayout
        GridLayout51                    matlab.ui.container.GridLayout
        byPCBButtonGroup                matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.RadioButton
        Arm4Button                      matlab.ui.control.RadioButton
        Arm3Button                      matlab.ui.control.RadioButton
        Arm2Button                      matlab.ui.control.RadioButton
        Arm1Button                      matlab.ui.control.RadioButton
        Label_2                         matlab.ui.control.Label
        TruedurationsecsLabel           matlab.ui.control.Label
        GridLayout49                    matlab.ui.container.GridLayout
        mustbechangedpriortoSHOWtotakeeffectLabel  matlab.ui.control.Label
        GridResolutionEditField         matlab.ui.control.NumericEditField
        GridResolutionEditFieldLabel    matlab.ui.control.Label
        stepsizetoiteratebyLabel        matlab.ui.control.Label
        IdxStepEditField                matlab.ui.control.NumericEditField
        IdxStepEditFieldLabel           matlab.ui.control.Label
        scatterplotmarkersizeLabel      matlab.ui.control.Label
        howoftentoplotLabel             matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel        matlab.ui.control.Label
        FrameStepEditField              matlab.ui.control.NumericEditField
        FrameStepEditFieldLabel         matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        Slider_3                        matlab.ui.control.Slider
        GridLayout44                    matlab.ui.container.GridLayout
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        PlayButton_2                    matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        SegmentsTab                     matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        GridLayout38                    matlab.ui.container.GridLayout
        Panel3                          matlab.ui.container.Panel
        GridLayout39_3                  matlab.ui.container.GridLayout
        UIAxes5                         matlab.ui.control.UIAxes
        Panel2                          matlab.ui.container.Panel
        GridLayout39_2                  matlab.ui.container.GridLayout
        UIAxes4                         matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        GridLayout39                    matlab.ui.container.GridLayout
        UIAxes3                         matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        ContextMenu                     matlab.ui.container.ContextMenu
        GotosegmentMenu                 matlab.ui.container.Menu
        startMenu                       matlab.ui.container.Menu
        endMenu                         matlab.ui.container.Menu
        playsegmentMenu                 matlab.ui.container.Menu
        DeletesegmentMenu               matlab.ui.container.Menu
        AddexistingsegmentsMenu         matlab.ui.container.Menu
        DeleteallMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Data        struct = struct     % Data
        Status      struct = struct     % App control
    end
    
    methods (Access = private)
        
        function nan_checker(app, segment)
            % check for nans in segment
            c = anynan(segment);
            switch c
                case true
                    app.Lamp.Color = [1,0,0];
                    app.SmoothButton.Enable = 'off';
                case false
                    app.Lamp.Color = [0,1,0];
                    app.SmoothButton.Enable = 'on';
            end
        end
        
        function plot_nan_segment(app, curr, reset_ax)
            arguments
                app
                curr        double              % current value
                reset_ax   logical  = true      % reset axes
            end
            
            % reset axes
            if reset_ax; cla(app.UIAxes2, 'reset'); end
            % time
            t = linspace(app.Status.nans.lbl_table.sec_0(curr), ...
                app.Status.nans.lbl_table.sec_end(curr), ...
                length(app.Status.nans.lbl_segments_pos{curr}'));
            
            switch app.Status.nans.pos_or_vel
                case 'Position'
                    signal = app.Status.nans.lbl_segments_pos{curr};
                case 'Velocity'
                    signal = diff([app.Status.nans.lbl_segments_pos{curr}(1,:); app.Status.nans.lbl_segments_pos{curr}]);
            end
            plot(t, signal, 'Parent', app.UIAxes2);
            legend(app.UIAxes2, {'X', 'Y', 'Z'});
            yticks(app.UIAxes2, 'auto');yticklabels(app.UIAxes2, 'auto');
            xticks(app.UIAxes2, 'auto');xticklabels(app.UIAxes2, 'auto');
            set(app.UIAxes2, 'Color', 'k', 'Xcolor', 'w','Ycolor', 'w', 'YGrid', 'on')
            set(get(app.UIAxes2, 'ylabel'), 'string', app.Status.nans.pos_or_vel)
            set(get(app.UIAxes2, 'xlabel'), 'string', 'Time (s)')
            set(app.UIAxes2.Legend, 'TextColor', 'k')
            
            % check if NaNs in current segment
            app.nan_checker(app.Status.nans.lbl_segments_pos{curr}) % only checks if gaps in position data
            
            % reset previous app.Status.nans.new_signal
            app.Status.nans.new_signal = signal;

            % update label
            app.Segment_NumberLabel.Text = sprintf('%i/%i', curr, app.Status.nans.num_lbls);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % UI
            movegui(app.UIFigure, 'center')
            app = setDefaultUI(app);
            app = colorMe(app, 'Simia');
            % Data
            [app.Data, app.Status] = setProperties(app.Data, app.Status);
        end

        % Key press function: UIFigure
        function fig_keypress(app, event)
            key = event.Key;

            if app.TabGroup.SelectedTab == app.KinematicsTab && app.TabGroup2.SelectedTab == app.nansTab
                switch key
                    case 'rightarrow'
                        try
                            app.Spinner.Value = app.Spinner.Value + 1;
                            app.SpinnerValueChanged()
                        catch
                            disp('out_of_range')
                        end
                    case 'leftarrow'
                        try
                            app.Spinner.Value = app.Spinner.Value - 1;
                            app.SpinnerValueChanged()
                        catch
                            disp('out_of_range')
                        end
                    case 's'
                        app.SmoothButtonPushed()
                    case 't'
                        t = struct(struct('Source', struct('Text', 'Interpolate')));
                        app.InterpolateButtonPushed(t);
                    case 'escape'
                        app.RESETButtonPushed()
                end                
            end
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            [file, path] = uigetfile({'*mp4'}, 'Select Video');
            % Back Focus
            figure(app.UIFigure);
            if path~= 0
                try
                    % reading video
                    vidObj = VideoReader([path, file]);
                    app.Data.beh.duration = vidObj.Duration;
                    app.Data.beh.vid_name = [app.Data.beh.vid_name, file];
                    app.Data.beh.default_FPS = vidObj.FrameRate;
                    app.Status.video_analysis.max_idx = vidObj.NumFrames;
                    app.Status.video_analysis.fps = vidObj.FrameRate;
                    app.Status.video_analysis.idx = 1;
                    
                    % set path as default path to save .mat table
                    app.Label.Text = path;
                    app.Status.video_analysis.dir_to_save = path;   % update

                    % day / rec number
                    [est_day, est_rec, est_monkey] = parse_vid_name(file);
                    app.DayEditField.Value = est_day;
                    app.RecEditField.Value = est_rec;
                    app.MonkeyEditField.Value = est_monkey;

                    i = 1;
                    out = cell(2, vidObj.NumFrames);
                    % UIProgress
                    d = uiprogressdlg(app.UIFigure,'Title','Loading Video',...
                                     'Message', 'Insert joke here', 'Cancelable','on');
                    while hasFrame(vidObj)  % read all frames
                        d.Value = i / vidObj.NumFrames;
                        % d.Message = sprintf('%12.9f', )
                        if d.CancelRequested; break; end
                        out{1, i} = readFrame(vidObj);
                        out{2, i} = vidObj.CurrentTime;
                        i = i + 1;
                    end
                    % close uipgrogress
                    close(d)
                    if i ~= vidObj.NumFrames + 1
                        msg = 'Video load cancelled early';
                        uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                        return
                    end
                    % save to public property
                    app.Data.beh.vid = out;

                    % showing first frame
                    cla(app.UIAxes)
                    imshow(out{1}, 'Parent', app.UIAxes);
                    axis(app.UIAxes, 'image')
                    app.UIAxes.Visible = 'on';

                    % check segments compatability
                    existing = app.UITable.Data;
                    % if no video loaded
                    if ~isempty(existing)
                        % get max frame to ensure compatability
                        max_new_frame = max([existing{:,3}]);
                        if app.Status.video_analysis.max_idx < max_new_frame
                            msg = 'Video frame and loaded frame segment indexes are not compatible';
                            uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                            return
                        end
                    end

                    % enabling / disabling
                    app.PlayButton.Enable = 'on';
                    app.GotosegmentMenu.Enable = 'on';
                    app.StartSegmentButton.Enable = 'on';
                    app.FPSEditField.Value = floor(vidObj.FrameRate);
                    app.Slider.MajorTickLabelsMode = 'auto';
                    app.Slider.Limits = [1, vidObj.NumFrames];
                    new_values = round(linspace(1, app.Data.beh.duration, length(app.Slider.MajorTickLabels)));
                    % Convert the array to a cell array of strings
                    new_majorticklabels = arrayfun(@num2str, new_values, 'UniformOutput', false);
                    app.Slider.MajorTickLabels = new_majorticklabels;
                    app.Slider.Enable = 'on';
                    app.GridLayout24.Visible = 'on';

                catch
                    % reset axis
                    cla(app.UIAxes, 'reset')
                    % something went wrong
                    msg = 'Error reading video. Try again';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                    % reset
                    startupFcn(app)
                end
            else
                % video not selected
                msg = 'No new video selected. Try again';
                uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            new_path = uigetdir("Select Folder to Save");
            % Back Focus
            figure(app.UIFigure);
            if new_path ~= 0
                app.Label.Text = new_path;
                app.Status.video_analysis.dir_to_save = new_path;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if strcmp(event.Source.Text, 'Play')
                if app.Slider.Value == app.Status.video_analysis.max_idx
                    app.Slider.Value = 1;
                end
                app.Status.video_analysis.play = true;
                app.PlayButton.Text = 'Stop';
            else
                app.Status.video_analysis.play = false;
                app.PlayButton.Text = 'Play';
            end

            % play video
            while app.Status.video_analysis.play
                % read frame given from the slider
                app.Status.video_analysis.idx = round(app.Slider.Value);
                imshow(app.Data.beh.vid{1, app.Status.video_analysis.idx}, 'Parent', app.UIAxes);
                pause(1/app.FPSEditField.Value);
                % check if ending
                if app.Status.video_analysis.idx == app.Status.video_analysis.max_idx
                    app.Status.video_analysis.play = false;
                    app.PlayButton.Text = 'Play';
                else
                    app.Slider.Value = app.Status.video_analysis.idx + 1;
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Status.video_analysis.idx = round(value);
            % show preview image if video not already playing
            if ~app.Status.video_analysis.play
                imshow(app.Data.beh.vid{1, app.Status.video_analysis.idx}, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: StartSegmentButton
        function StartSegmentButtonPushed(app, event)
            % start segment for labeling
            if strcmp(event.Source.Text, 'Start Segment')
                app.Status.video_analysis.start_segment = app.Status.video_analysis.idx;
                app.StartSegmentButton.Text = 'Stop Segment';
            else
                % end of segment
                app.Status.video_analysis.end_segment = app.Status.video_analysis.idx;
                if app.Status.video_analysis.play
                    % end video play if running
                    app.Status.video_analysis.play = false;
                    app.PlayButton.Text = 'Play';
                end
                % time to choose what to label segment
                app.PlayButton.Enable = 'off';
                app.selectButton.Enable = 'on';
                app.selectButton_2.Enable = 'on';
                app.selectButton_3.Enable = 'on';
                app.selectButton_4.Enable = 'on';
                app.Button_4.Enable = 'off';
                app.Button_3.Enable = 'off';
                app.Button_2.Enable = 'off';
                app.Button.Enable = 'off';
                % change back name of button + disable it
                app.StartSegmentButton.Text = 'Start Segment';
                app.StartSegmentButton.Enable = 'off';
            end
        end

        % Button pushed function: selectButton, selectButton_2, 
        % ...and 2 other components
        function selectButtonPushed(app, event)
            % find what label it was
            switch event.Source.Tag
                case 'first'
                    lbl = app.EditField.Value;
                case 'second'
                    lbl = app.EditField2.Value;
                case 'third'
                    lbl = app.EditField3.Value;
                case 'fourth'
                    lbl = app.EditField4.Value;
            end
            % add to table
            current_t = app.UITable.Data;
            if app.Status.video_analysis.start_segment <= app.Status.video_analysis.end_segment
                new_row = {lbl, app.Status.video_analysis.start_segment, app.Status.video_analysis.end_segment, ...
                            app.Data.beh.vid{2, app.Status.video_analysis.start_segment}, ...
                            app.Data.beh.vid{2, app.Status.video_analysis.end_segment}};
                app.UITable.Data = [current_t;new_row];
            else
                % video not selected
                msg = 'SEGMENT ERROR: Start > End';
                uialert(app.UIFigure, msg, 'Woopsie', 'Icon','warning', 'Modal',true);
            end

            % re-enable the buttons
            app.PlayButton.Enable = 'on';
            app.Button_4.Enable = 'on';
            app.Button_3.Enable = 'on';
            app.Button_2.Enable = 'on';
            app.Button.Enable = 'on';
            app.selectButton.Enable = 'off';
            app.selectButton_2.Enable = 'off';
            app.selectButton_3.Enable = 'off';
            app.selectButton_4.Enable = 'off';
            app.StartSegmentButton.Enable = 'on';
        end

        % Callback function: DeletesegmentMenu, UITable
        function UITableDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            app.UITable.Data(displayRow, :) = [];
        end

        % Button pushed function: SaveSegmentsButton
        function SaveSegmentsButtonPushed(app, event)
            % get data
            t_to_save = app.UITable.Data;
            path_to_save = app.Status.video_analysis.dir_to_save;
            
            if size(app.UITable.Data, 1) == 0
                msg = 'No segments detected.';
                uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                return
            end
            
            % name of save
            default = ['SIMIA_labels_', app.MonkeyEditField.Value, '_', app.DayEditField.Value, '_', app.RecEditField.Value, '.mat'];
            
            title = 'Saving Segments';
            msg = sprintf(['Creating file in %s\n' ...
                           'Number of Segments: %d\n' ...
                           'Name of file: %s'], ...
                           path_to_save, ...
                           size(app.UITable.Data, 1), ...
                           default);

            selection = uiconfirm(app.UIFigure, msg, title, 'Options', ...
                                 {'Save', 'Cancel'}, ...
                                 'DefaultOption', 1, 'CancelOption', 2);

            if strcmp(selection, 'Save')
                save(default, 't_to_save');
            elseif strcmp(selection, 'Cancel')
                return
            end
        end

        % Menu selected function: startMenu
        function startMenuSelected(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            if ~isempty(displayRow)
                start_frame = app.UITable.Data{displayRow, 2};
                a = struct();
                a.Source.Text = "Stop";
                PlayButtonPushed(app, a);
                app.Slider.Value = start_frame;
                SliderValueChanged(app, a);
            end
        end

        % Menu selected function: endMenu
        function endMenuSelected(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            if ~isempty(displayRow)
                end_frame = app.UITable.Data{displayRow, 3};
                a = struct();
                a.Source.Text = "Stop";
                PlayButtonPushed(app, a);
                app.Slider.Value = end_frame;
                SliderValueChanged(app, a);
            end
        end

        % Menu selected function: playsegmentMenu
        function playsegmentMenuSelected(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            if ~isempty(displayRow)
                start_frame = app.UITable.Data{displayRow, 2};
                end_frame = app.UITable.Data{displayRow, 3};
                a = struct();
                a.Source.Text = "Stop";
                PlayButtonPushed(app, a);
                app.Slider.Value = start_frame;
                SliderValueChanged(app, a);
                % disable buttons
                app.GridLayout14.Visible = 'off';
                app.GridLayout24.Visible = 'off';
                while app.Slider.Value < end_frame
                    % read frame given from the slider
                    imshow(app.Data.beh.vid{1, app.Slider.Value}, 'Parent', app.UIAxes);
                    pause(1/app.FPSEditField.Value);
                    % update
                    app.Slider.Value = app.Slider.Value + 1;
                end
                % enable buttons
                app.GridLayout14.Visible = 'on';
                app.GridLayout24.Visible = 'on';
            end
        end

        % Button pushed function: Button, Button_2, Button_3, Button_4
        function Button_3Pushed(app, event)
            a = struct();
            a.Source.Text = "Stop";
            PlayButtonPushed(app, a);

            switch event.Source.Text
                case '>'
                    if app.Slider.Value == app.Status.video_analysis.max_idx
                        app.Slider.Value = app.Status.video_analysis.max_idx;
                    else
                        app.Slider.Value = app.Slider.Value + 1;
                    end
                case '<'
                    if app.Slider.Value == 1
                        app.Slider.Value = 1;
                    else
                        app.Slider.Value = app.Slider.Value - 1;
                    end
                case '+10'
                    if app.Slider.Value + 10 >= app.Status.video_analysis.max_idx
                        app.Slider.Value = app.Status.video_analysis.max_idx;
                    else
                        app.Slider.Value = app.Slider.Value + 10;
                    end
                case '-10'
                    if app.Slider.Value - 10 <= 1
                        app.Slider.Value = 1;
                    else
                        app.Slider.Value = app.Slider.Value - 10;
                    end
            end

            % update 
            SliderValueChanged(app, a);
        end

        % Menu selected function: AddexistingsegmentsMenu
        function AddexistingsegmentsMenuSelected(app, event)
            [file, path] = uigetfile({'*mat'}, 'Select .mat file');
            % Back Focus
            figure(app.UIFigure);
            
            if path~= 0
                try
                    t = load([path, file]); % should have 't_to_save' variable

                    title = 'Loading SIMIA Segments';
                    msg = sprintf(['File name: %s\n' ...
                                   'Number of Segments Detected: %d\n' ...
                                   '\n' ...
                                   'Note: Loading SIMIA file will replace existing labels by default.'], ...
                                   file, ...
                                   size(t.t_to_save, 1));
        
                    selection = uiconfirm(app.UIFigure, msg, title, 'Options', ...
                                         {'Load', 'Merge & Load', 'Cancel'}, ...
                                 'DefaultOption', 1, 'CancelOption', 3);
                    
                    % options
                    if strcmp(selection, 'Load')
                        app.UITable.Data = t.t_to_save;
                        return

                    elseif strcmp(selection, 'Merge & Load')
                        existing = app.UITable.Data;
                        
                        % if no video loaded
                        if app.Status.video_analysis.max_idx == 0
                            app.UITable.Data = [existing;t.t_to_save];
                            return
                        end
                            
                        % get max frame to ensure compatability
                        max_new_frame = max([existing{:,3}]);
                        
                        % if video loaded
                        if app.Status.video_analysis.max_idx > 0 && app.Status.video_analysis.max_idx > max_new_frame
                            app.UITable.Data = [existing;t.t_to_save];
                        % if compatability errors
                        elseif app.Status.video_analysis.max_idx > 0 && app.Status.video_analysis.max_idx < max_new_frame
                            % segment index error
                            msg = sprintf('SIMIA segments and current video loaded are not compatible.\n\n Max segment frame > max video frame.\n\nLoad new SIMIA segment file or create segments.');
                            uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                            return
                        end

                        % app.UITable.Data = file;
                    elseif strcmp(selection, 'Cancel')
                        return
                    end

                catch
                    % something went wrong
                    msg = 'Error reading segment file. Make sure it is the right format/version!';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                end
            end
        end

        % Button pushed function: LoadDLCButton
        function LoadDLCButtonPushed(app, event)
            [file, path] = uigetfile({'*mat'}, 'Select .mat file');
            % Back Focus
            figure(app.UIFigure);
            cla(app.UIAxesVel, 'reset')
            cla(app.UIAxesPos, 'reset')
            if path~= 0
                try
                    data = load([path, file]);
                    if ismember(fields(data), 'mod_all_pos3d')
                        msg = 'Modified dlc file detected. Proceed if intented, otherwise load new DLC file.';
                        uialert(app.UIFigure, msg, 'FYI', 'Icon','info', 'Modal',true);
                        app.Data.beh.all_pos3d = data.mod_all_pos3d;
                    elseif ismember(fields(data), 'all_pos3d')
                        app.Data.beh.all_pos3d = data.all_pos3d;
                    else
                        msg = 'WTF you trying to load mate.';
                        uialert(app.UIFigure, msg, 'nah', 'Icon','error', 'Modal',true);
                        return
                    end
                    [roi_num, xyz, frames] = size(app.Data.beh.all_pos3d);

                    % set up listbox
                    % app.ListBox.Items  = arrayfun(@(x) ['ROI - ' num2str(x)], 1:roi_num, 'UniformOutput',false);
                    app.ListBox.Items = {'Hand', 'Wrist', 'Elbow', 'Wand Tip', 'Wand Shaft'};
                    app.ListBox.Value = 'Wrist';

                    % stacked position plot
                    if roi_num ~= 5
                        msg = 'Error in DLC. Not the right number of ROIs (should be 5)';
                        uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                        return
                    end
                    roi = 2;    % by default = 'wrist'
                    data_roi = app.Data.beh.all_pos3d(roi, :,:);

                    C = squeeze(data_roi)';
                    offset_pos = max(C(:)) * (0:xyz-1)';                   % get offset
                    pos_data = zeros(frames, xyz);
                    for pos = 1:xyz
                        pos_data(:,pos) = C(:, pos) + offset_pos(pos);
                    end
                    plot(1:frames, pos_data, 'Parent', app.UIAxesPos);     % plot
                    line_names = {'x', 'y', 'z'};                          % labeling the y-axis
                    x_lines = zeros(1, xyz);
                    y_lines = pos_data(1, 1:xyz);
                    text(x_lines, y_lines, line_names, 'Color', 'w', 'Parent', app.UIAxesPos)   % plotting the label with text()
                    set(app.UIAxesPos, 'Color', 'k', 'Xcolor', 'w', 'YTick', [], 'Ycolor', 'w', 'YGrid', 'on') 
                    set(get(app.UIAxesPos, 'ylabel'), 'string', 'Dimension')
                    app.PositionPanel.Title = "Position";
                    % velocity
                    C_vel = diff([zeros(1,3); C]);
                    % magnitude
                    C_vel_mag = sqrt(sum(C_vel.^2, 2));
                    % normalization of magnitude
                    norm_vel = normalize(C_vel_mag, 'zscore');
                    plot(1:frames, norm_vel, 'Parent', app.UIAxesVel);
                    set(app.UIAxesVel, 'Color', 'k', 'Xcolor', 'w','Ycolor', 'w')
                    yticks(app.UIAxesVel, 'auto');yticklabels(app.UIAxesVel, 'auto');
                    set(get(app.UIAxesVel, 'ylabel'), 'string', 'Normalized Velocity Magnitude')
                    
                    % visibilities
                    app.PositionPanel.Visible = 'on';
                    app.VelocityPanel.Visible = 'on';
                    app.AutoSegmentButton.Enable = 'on';
                    % slider mechanics
                    app.Slider_2.Limits = [1, frames];
                    app.Slider_2.Value = [1, frames];
                    app.Slider_2.Visible = 'on';
                catch
                    cla(app.UIAxesVel, 'reset')
                    cla(app.UIAxesPos, 'reset')
                    % something went wrong
                    msg = 'Error reading dlc file. Make sure it is the rec#.dlc.all_pos3d.mat file!';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxesVel, 'reset')
            cla(app.UIAxesPos, 'reset')
            
            % TODO
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            axis(app.UIAxesVel, 'auto y')
            axis(app.UIAxesPos, 'auto y')
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            if app.Status.kinematics.axis_vel_only
                xlim(app.UIAxesVel, changingValue)
                return
            end
            xlim(app.UIAxesPos, changingValue)
            xlim(app.UIAxesVel, changingValue)
        end

        % Button pushed function: AutoSegmentButton
        function AutoSegmentButtonPushed(app, event)
            roi = app.ListBox.ValueIndex;
            data_roi = app.Data.beh.all_pos3d(roi, :,:);
            C = squeeze(data_roi)';

            % smoothing
            if app.PositionMovAvgWindowEditField.Value > 0
                if app.Data.beh.default_FPS == 0
                    app.Data.beh.default_FPS = 30;
                end
                num_samples = ceil(app.PositionMovAvgWindowEditField.Value*1e-3 * app.Data.beh.default_FPS);
                C = smoothdata(C, "movmean", num_samples);
            end

            % vel
            C_vel = diff([zeros(1,3); C]);
            C_vel_mag = sqrt(sum(C_vel.^2, 2));
            % normalization of magnitude
            norm_vel = normalize(C_vel_mag, 'zscore');
            
            % reach
            norm_vel(norm_vel > app.UpperNormMagThresholdEditField.Value) = NaN;  % get rid of those above the threshold
            all_above_thresh_idx = find(norm_vel>app.ReachThresholdEditField.Value);  % get idx vals above thresh
            dist_between_thresh = diff(all_above_thresh_idx);
            % reach segmentation
            reach_idx = reach_segmentation(all_above_thresh_idx', ...
                                           dist_between_thresh, ...
                                           ceil(app.MinTimeBWReachEditField.Value*1e-3 * app.Data.beh.default_FPS), ...
                                           ceil(app.EditField_2.Value*1e-3 * app.Data.beh.default_FPS));
            app.TextArea.Value = sprintf('Found %d points above reach threshold\n\nFound %d good reach points', length(all_above_thresh_idx), length(reach_idx));

            % plot results
            cla(app.UIAxesVel,'reset')
            cla(app.UIAxesPos, 'reset')
            % distance vs duration
            plot(dist_between_thresh, 'r', 'Parent', app.UIAxesPos);    % plot
            set(app.UIAxesPos, 'Color', 'k', 'Xcolor', 'w', 'YTick', [], 'Ycolor', 'w', 'YGrid', 'on') 
            yticks(app.UIAxesPos, 'auto');
            yticklabels(app.UIAxesPos, app.UIAxesPos.YTick/30);
            xticks(app.UIAxesPos, 'auto'); xticklabels(app.UIAxesPos, 'auto')
            set(get(app.UIAxesPos, 'ylabel'), 'string', 'Time Between Threshold (s)')
            set(get(app.UIAxesPos, 'xlabel'), 'string', 'Duration')
            app.PositionPanel.Title = "Distance vs Duration";
            % vel
            plot(norm_vel, 'Parent', app.UIAxesVel);
            hold(app.UIAxesVel, 'on')
            scatter(all_above_thresh_idx, norm_vel(all_above_thresh_idx), 'red', 'Parent', app.UIAxesVel)
            scatter(reach_idx, norm_vel(reach_idx), "green", 'filled', 'Parent', app.UIAxesVel)
            hold(app.UIAxesVel, 'off')
            set(app.UIAxesVel, 'Color', 'k', 'Xcolor', 'w','Ycolor', 'w', 'YGrid', 'on')
            yticks(app.UIAxesVel, 'auto');yticklabels(app.UIAxesVel, 'auto');
            set(get(app.UIAxesVel, 'ylabel'), 'string', 'Normalized Velocity Magnitude')
            legend(app.UIAxesVel, {'Norm Velocity', 'Reach Threshold', 'Clean Reach Detected'})
            set(app.UIAxesVel.Legend, 'TextColor', 'w')

            % slider mechanics
            app.Status.kinematics.axis_vel_only = 1;
            app.Slider_2.Limits = [1, length(norm_vel)];
            app.Slider_2.Value = [1, length(norm_vel)];
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if isempty(app.UITable.Data) | isempty(app.Data.beh.all_pos3d)
                disp("Empty Data")
                return
            end

            % ensure compatability
            % TODO: FIX BC ALLPOSrec1 works on rec2 segments!
            max_label_idx = max([app.UITable.Data{:, 3}]);
            max_allpos_idx = size(app.Data.beh.all_pos3d, 3);
            if max_allpos_idx < max_label_idx
                disp("Incompatible")
                return
            end

            load_app_simia(app, app.UITable.Data);
        end

        % Button pushed function: LoadSegmentsButton
        function LoadSegmentsButtonPushed(app, event)
            % get chosen label
            lbl = app.ListBox_2.Value;
            app.VelocitySwitch.Value = 'Position';
            % get table in correct position
            T = cell2table(app.UITable.Data, "VariableNames", {'Label', 'frame_0', 'frame_end', 'sec_0', 'sec_end'});
            % save to temporary variable
            app.Status.nans.lbl_table = T(strcmp(T.Label, lbl), :);                         % table to make changes into
            app.Status.nans.num_lbls = height(app.Status.nans.lbl_table);                   % number of reaches
            app.Status.nans.current_lbl = 1;                                                % starting the current label
            app.Spinner.Value = app.Status.nans.current_lbl;                                % spinner current value = 1
            app.Spinner.Limits = [1, app.Status.nans.num_lbls];                             % spinner limits
            app.Segment_NumberLabel.Text = sprintf('%i/%i', app.Status.nans.current_lbl, ...% segment number label
                                                            app.Status.nans.num_lbls);
                
            % get segments
            app.Status.nans.lbl_segments_pos = cell(1, app.Status.nans.num_lbls);
            app.Status.nans.original_lbls = cell(1, app.Status.nans.num_lbls);         % default so we can easily reset edits

            roi = 2; % wrist hard coded for now TODO
            for i = 1:app.Status.nans.num_lbls
                idx_start = app.Status.nans.lbl_table.frame_0(i) - app.earlystartEditField.Value;
                idx_end =  app.Status.nans.lbl_table.frame_end(i);
                app.Status.nans.lbl_segments_pos{i} = squeeze(app.Data.beh.all_pos3d(roi, :, idx_start:idx_end))';
                app.Status.nans.original_lbls{i} = squeeze(app.Data.beh.all_pos3d(roi, :, idx_start:idx_end))';
            end

            % default enabling
            app.UIAxes2.Visible = 'on';
            app.Spinner.Enable = 'on';
            app.SmoothButton.Enable = 'on';
            app.ImputationMeanButton.Enable = 'on';
            app.InterpolateButton.Enable = 'on';
            app.RESETButton.Enable = 'on';
            app.VelocitySwitch.Enable = 'on';

            % plot first segment
            app.VelocitySwitch.Value = 'Position';
            app.Status.nans.pos_or_vel = 'Position';    % reset status
            app.plot_nan_segment(app.Status.nans.current_lbl) % position by default
        end

        % Value changed function: VelocitySwitch
        function VelocitySwitchValueChanged(app, event)
            value = app.VelocitySwitch.Value;
            app.Status.nans.pos_or_vel = value;
            % update plot
            app.plot_nan_segment(app.Status.nans.current_lbl);
        end

        % Button pushed function: ImputationMeanButton, InterpolateButton
        function InterpolateButtonPushed(app, event)
            signal = app.Status.nans.lbl_segments_pos{app.Status.nans.current_lbl};

            switch event.Source.Text
                case 'Interpolate'
                    thresholdFactor = app.ThresholdFactorEditField.Value;
                    for i = 1:3 % Loop through x, y, z dimensions
                        row = signal(:, i);
                        nonNanIndices = ~isnan(row);
                        meanValue = mean(row(nonNanIndices));
                        stdDev = std(row(nonNanIndices));
                        lowerBound = mean(meanValue - thresholdFactor * stdDev);
                        upperBound = mean(meanValue + thresholdFactor * stdDev);
                        
                        % disp([meanValue, stdDev, lowerBound, upperBound])

                        nanIndices = isnan(row);
                        interpolatedValues = interp1(find(nonNanIndices), row(nonNanIndices), find(nanIndices), 'linear', 'extrap');
                        
                        % Apply threshold bounds to interpolated values
                        interpolatedValues(interpolatedValues < lowerBound) = lowerBound;
                        interpolatedValues(interpolatedValues > upperBound) = upperBound;
                        
                        row(nanIndices) = interpolatedValues;
                        signal(:, i) = row;
                    end

                case 'Imputation Mean'
                    % Replace NaN values with the mean of non-NaN values in their respective row.
                    for i = 1:3 % Loop through x, y, z dimensions
                        row = signal(i, :);
                        nanIndices = isnan(row);
                        meanValue = mean(row(~nanIndices));
                        row(nanIndices) = meanValue;
                        signal(i, :) = row;
                    end
            end
            
            % temp save
            app.Status.nans.lbl_segments_pos{app.Status.nans.current_lbl} = signal;
            % plotting
            app.plot_nan_segment(app.Status.nans.current_lbl)
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            signal = app.Status.nans.lbl_segments_pos{app.Status.nans.current_lbl};

            % smoothing
            if app.smoothwindowmsEditField.Value > 0 && strcmp(app.SmoothButton.Enable, 'on')
                if app.Data.beh.default_FPS == 0
                    app.Data.beh.default_FPS = 30;
                end
                num_samples = ceil(app.smoothwindowmsEditField.Value*1e-3 * app.Data.beh.default_FPS);
                b = (1/num_samples)*ones(1,num_samples);  % Moving average filter coefficients
                a = 1;  % Denominator coefficient for filtfilt (FIR filter)
                for i = 1:3
                    row = signal(:,i);
                    % signal(i,:) = smoothdata(row, "movmean", num_samples);
                    signal(:, i) = filtfilt(b, a, row);  % Apply zero-phase digital filtering
                end
            else
                return
            end

            % temp save
            app.Status.nans.lbl_segments_pos{app.Status.nans.current_lbl} = signal;
            % plotting
            app.plot_nan_segment(app.Status.nans.current_lbl, false)
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            % switch back to position
            app.VelocitySwitch.Value = 'Position';
            app.Status.nans.pos_or_vel = 'Position';    % reset status

            % reset
            app.Status.nans.lbl_segments_pos{app.Status.nans.current_lbl} = app.Status.nans.original_lbls{app.Status.nans.current_lbl};

            % update plot
            app.plot_nan_segment(app.Status.nans.current_lbl)
        end

        % Button pushed function: SavePos3DChangesButton
        function SavePos3DChangesButtonPushed(app, event)
            t = app.Status.nans.lbl_table;
            roi = 2; % wrist hard coded for now
            mod_all_pos3d = app.Data.beh.all_pos3d;
            for i = 1:app.Status.nans.num_lbls
                idx_start = t.frame_0(i) - app.earlystartEditField.Value;
                idx_end =  t.frame_end(i);
                % get desired signal
                signal = app.Status.nans.lbl_segments_pos{i};
                % set into its idx
                mod_all_pos3d(roi, :, idx_start:idx_end) = signal;
            end
            % save
            app.Data.beh.mod_all_pos3d = mod_all_pos3d;
            
            % for now...
            prompt = {'Enter file name:'};
            dlgtitle = 'Input';
            fieldsize = [1 45];
            definput = {'rec00#'};
            name_of_file = inputdlg(prompt,dlgtitle,fieldsize,definput, "on");
            if ~isempty(name_of_file)
                complete_name = sprintf("%s.dlc.mod_all_pos3d.mat", name_of_file{:});
                save(complete_name, "mod_all_pos3d")
            end
        end

        % Menu selected function: DeleteallMenu
        function DeleteallMenuSelected(app, event)
            t = app.UITable.Data;
            title = 'Delete All';
            msg = sprintf(['Remove all current segments\n' ...
                           'Number of segments: %d\n' ...
                           '\n' ...
                           'Note: Deleted SIMIA labels cannot be recovered. Make sure you saved them :)'], ...
                           size(t, 1));

            selection = uiconfirm(app.UIFigure, msg, title, 'Options', ...
                                 {'Delete All', 'Cancel'}, ...
                                 'DefaultOption', 1, 'CancelOption', 2);

            if strcmp(selection, 'Delete All')
                app.UITable.Data = {};
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            % update
            app.UITable.Data = newDisplayData;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.Status.nans.current_lbl = app.Spinner.Value;
            % update plot
            app.plot_nan_segment(app.Status.nans.current_lbl)
        end

        % Button pushed function: LoadNeuralButton
        function LoadNeuralButtonPushed(app, event)
            [file, path] = uigetfile({'*mat'}, 'Select .mat file');

            % Back Focus
            figure(app.UIFigure);
            
            if path~= 0
                try 
                    % UIprogressdlg
                    d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
                    'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');

                    load_raw_ecog(app, [path, file], event);
                    % Listbox
                    if strcmp(app.Data.eeg.flag, "") || strcmp(app.Data.eeg.flag, "struct")
                        app.ListBox_3.Items = app.Data.eeg.files.Name;
                        % app.ListBox_3.Enable = 'on';
                    else
                        app.TextArea_3.Value = sprintf("%s\n%s",app.TextArea_3.Value{:}, ...
                                                            app.G.Encoding.flag);
                    end

                catch
                    % something went wrong
                    msg = 'Error reading file. Make sure it is a .mat file!';
                    uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true);
                end
                
                % Close UIProgress
                close(d);
            end
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            app.ListBox_3DoubleClicked()
        end

        % Double-clicked callback: ListBox_3
        function ListBox_3DoubleClicked(app, event)
            value = app.ListBox_3.Value;
            % Find file
            row = app.Data.eeg.files(strcmp(app.Data.eeg.files.Name, value), :);
            % Update
            txt = sprintf("          name: %s\n" + ...
                    "                       size: [%s]\n" + ...
                    "                    class: %s\n", ...
                        row.Name{:}, row.Size{:}, row.Class{:});
            app.TextArea_3.Value = sprintf(['>>File Selected\n>>info\n' ...
                                          '           %s'], txt);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Load Selection
            value = app.ListBox_3.Value;

            %% Check Selection
            % Empty Selection
            if isempty(value)
                new_msg = sprintf(">>No value selected. Try again.");
                app.TextArea_3.Value = vertcat(app.TextArea_3.Value, new_msg);
            end

            % Repetitive Selection
            if isequal(value, app.Data.eeg.last_val_load)
                new_msg = sprintf("\n>>Variable already loaded.\n" + ...
                                  ">>Continue analysis or load new data.");
                app.TextArea_3.Value = vertcat(app.TextArea_3.Value, new_msg);
                return
            end

            %% New Table
            % Find rows with matching names
            matchingRows = ismember(app.Data.eeg.files.Name, value);
            % Create a new table with matching rows
            newTable = app.Data.eeg.files(matchingRows, :);
            
            % UIprogressdlg
            d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
            'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');

            %% FILE
            out = select_eeg(app, newTable);

            % Flag check
            if strcmpi(app.Data.eeg.flag, "struct")
                load_raw_ecog(app, '', event)
                % Listbox
                if strcmp(app.Data.eeg.flag, "") || strcmp(app.Data.eeg.flag, "struct")
                    app.ListBox_3.Items = app.Data.eeg.files.Name;
                    % app.ListBox_3.Enable = 'on';
                else
                    app.TextArea_3.Value = sprintf("%s\n%s",app.TextArea_3.Value{:}, ...
                                                        app.G.Encoding.flag);
                end
                app.ListBox_3DoubleClicked
                return
            end

            %% Out
            % Close UIProgress
            close(d);

            % Update Selection
            if strcmp(app.Data.eeg.flag, 'double')
                app.Data.eeg.original = out;
                app.Data.eeg.last_val_load = value;

                % Update TextArea
                new_msg = sprintf("\n>>Load successful.\n" + ...
                                  "**Happy monkey noises**\n");
                app.TextArea_3.Value = vertcat(app.TextArea_3.Value, new_msg);
            else
                % Update TextArea
                new_msg = sprintf("\n>>Data type %s not supported. Only double", app.Data.eeg.flag);
                app.TextArea_3.Value = vertcat(app.TextArea_3.Value, new_msg);
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            asciiArt = {
                            '        .="=.'
                            '      _/.-.-.\_     _'
                            '     ( ( o o ) )    ))'
                            '      |/  "  \|       //'
                            '      \"---"/       //'
                            '       /`"""`\\   (('
                            '      / /_,_\\    \\'
                            '      \_\\_"_/ \  ))'
                            '       /`  /`~\  |//'
                            '      /   /    \   /'
                            '  ,--`,--''\/\    /'
                            '   ''-- "--''  ''--'''
                        };

            app.TextArea_3.Value = vertcat(app.TextArea_3.Value, asciiArt);

            play_neural(app, event)
        end

        % Button pushed function: SHOWButton
        function SHOWButtonPushed(app, event)
            prep_neural(app);
        end

        % Button pushed function: LoadGooseButton
        function LoadGooseButtonPushed(app, event)
            % [file, path] = uigetfile({'*mat'}, 'Select .mat file');

            % Back Focus
            % figure(app.UIFigure);
            
            % if path~= 0
            try 
                temp = load('el_data.mat');
                app.Data.eeg.el_x = temp.X;
                app.Data.eeg.el_y = temp.Y;
                app.Data.eeg.pcb_id = temp.pcb_id;

                % Update TextArea
                new_msg = sprintf("\n>>Goose electrode data loaded!");
                app.TextArea_3.Value = vertcat(app.TextArea_3.Value, new_msg);
           
            catch
                disp("goose el_data.mat not in path")
            end
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @fig_keypress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.VideoAnalysisTab = uitab(app.TabGroup);
            app.VideoAnalysisTab.Title = 'Video Analysis';
            app.GridLayout3 = uigridlayout(app.VideoAnalysisTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.SelectVideoButton = uibutton(app.GridLayout5, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.LabelsPanel = uipanel(app.GridLayout4);
            app.LabelsPanel.Title = 'Labels';
            app.GridLayout8 = uigridlayout(app.LabelsPanel);
            app.EditField = uieditfield(app.GridLayout8, 'text');
            app.EditField2 = uieditfield(app.GridLayout8, 'text');
            app.EditField3 = uieditfield(app.GridLayout8, 'text');
            app.EditField4 = uieditfield(app.GridLayout8, 'text');
            app.selectButton = uibutton(app.GridLayout8, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Tag = 'first';
            app.selectButton.Text = 'select';
            app.selectButton_2 = uibutton(app.GridLayout8, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_2.Tag = 'second';
            app.selectButton_2.Text = 'select';
            app.selectButton_3 = uibutton(app.GridLayout8, 'push');
            app.selectButton_3.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_3.Tag = 'third';
            app.selectButton_3.Text = 'select';
            app.selectButton_4 = uibutton(app.GridLayout8, 'push');
            app.selectButton_4.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton_4.Tag = 'fourth';
            app.selectButton_4.Text = 'select';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.DropDown = uidropdown(app.GridLayout10);
            app.DropDown.Items = {'.mat'};
            app.DropDown.Value = '.mat';
            app.SaveSegmentsButton = uibutton(app.GridLayout10, 'push');
            app.SaveSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentsButtonPushed, true);
            app.SaveSegmentsButton.Text = 'Save Segments';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.DIRLabel = uilabel(app.GridLayout12);
            app.DIRLabel.Text = 'DIR:';
            app.ChangeButton = uibutton(app.GridLayout12, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.Label = uilabel(app.GridLayout11);
            app.GridLayout35 = uigridlayout(app.GridLayout9);
            app.DayEditFieldLabel = uilabel(app.GridLayout35);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.GridLayout35, 'text');
            app.RecEditFieldLabel = uilabel(app.GridLayout35);
            app.RecEditFieldLabel.Text = 'Rec';
            app.RecEditField = uieditfield(app.GridLayout35, 'text');
            app.MonkeyEditFieldLabel = uilabel(app.GridLayout35);
            app.MonkeyEditFieldLabel.Text = 'Monkey';
            app.MonkeyEditField = uieditfield(app.GridLayout35, 'text');
            app.SegmentsPanel = uipanel(app.GridLayout4);
            app.SegmentsPanel.Title = 'Segments';
            app.GridLayout7 = uigridlayout(app.SegmentsPanel);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Label'; 'Start (Frame)'; 'End (Frame)'; 'Start (sec)'; 'End (sec)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true];
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout6);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout13 = uigridlayout(app.GridLayout6);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.PlayButton = uibutton(app.GridLayout14, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StartSegmentButton = uibutton(app.GridLayout14, 'push');
            app.StartSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentButtonPushed, true);
            app.StartSegmentButton.Text = 'Start Segment';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.FPSEditFieldLabel = uilabel(app.GridLayout15);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.GridLayout15, 'numeric');
            app.FPSEditField.Limits = [0 100];
            app.GridLayout25 = uigridlayout(app.GridLayout13);
            app.GridLayout24 = uigridlayout(app.GridLayout25);
            app.Button = uibutton(app.GridLayout24, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button.Text = '-10';
            app.Button_2 = uibutton(app.GridLayout24, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_2.Text = '<';
            app.Button_3 = uibutton(app.GridLayout24, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>';
            app.Button_4 = uibutton(app.GridLayout24, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_4.Text = '+10';
            app.Slider = uislider(app.GridLayout6);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.GridLayout16 = uigridlayout(app.KinematicsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout16);
            app.allposTab = uitab(app.TabGroup2);
            app.allposTab.Title = 'all pos';
            app.GridLayout17 = uigridlayout(app.allposTab);
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.LoadDLCButton = uibutton(app.GridLayout18, 'push');
            app.LoadDLCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDLCButtonPushed, true);
            app.LoadDLCButton.Text = 'Load DLC';
            app.SelectDLCallposition3DmatfileforstudyLabel = uilabel(app.GridLayout18);
            app.SelectDLCallposition3DmatfileforstudyLabel.Text = 'Select DLC all position 3D .mat file for study';
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.ListBox = uilistbox(app.GridLayout20);
            app.ListBox.Items = {'Hand', 'Wrist', 'Elbow', 'Wand Tip', 'Wand Shaft'};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Wrist';
            app.TextArea = uitextarea(app.GridLayout20);
            app.GridLayout21 = uigridlayout(app.GridLayout19);
            app.PositionPanel = uipanel(app.GridLayout21);
            app.PositionPanel.Title = 'Position';
            app.GridLayout22 = uigridlayout(app.PositionPanel);
            app.UIAxesPos = uiaxes(app.GridLayout22);
            app.UIAxesPos.XTick = [];
            app.UIAxesPos.XTickLabel = '';
            app.UIAxesPos.YTick = [];
            app.VelocityPanel = uipanel(app.GridLayout21);
            app.VelocityPanel.Title = 'Velocity';
            app.GridLayout23 = uigridlayout(app.VelocityPanel);
            app.UIAxesVel = uiaxes(app.GridLayout23);
            app.UIAxesVel.XTick = [];
            app.UIAxesVel.YTick = [];
            app.Slider_2 = uislider(app.GridLayout21, 'range');
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.AutoSegmentPanel = uipanel(app.GridLayout21);
            app.AutoSegmentPanel.Title = 'Auto Segment';
            app.GridLayout26 = uigridlayout(app.AutoSegmentPanel);
            app.MethodDropDown = uidropdown(app.GridLayout26);
            app.MethodDropDown.Items = {'Min Reach Duration'};
            app.MethodDropDown.Value = 'Min Reach Duration';
            app.ReachThresholdEditFieldLabel = uilabel(app.GridLayout26);
            app.ReachThresholdEditFieldLabel.Text = 'Reach Threshold';
            app.ReachThresholdEditField = uieditfield(app.GridLayout26, 'numeric');
            app.ReachThresholdEditField.Limits = [0 9];
            app.ReachThresholdEditField.Placeholder = '0.5';
            app.ReachThresholdEditField.Value = 0.5;
            app.AutoSegmentButton = uibutton(app.GridLayout26, 'push');
            app.AutoSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSegmentButtonPushed, true);
            app.AutoSegmentButton.Enable = 'off';
            app.AutoSegmentButton.Text = 'Auto-Segment';
            app.PositionMovAvgWindowEditFieldLabel = uilabel(app.GridLayout26);
            app.PositionMovAvgWindowEditFieldLabel.Text = 'Position Mov. Avg Window';
            app.PositionMovAvgWindowEditField = uieditfield(app.GridLayout26, 'numeric');
            app.PositionMovAvgWindowEditField.Limits = [0 5000];
            app.PositionMovAvgWindowEditField.Value = 250;
            app.ResetButton = uibutton(app.GridLayout26, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.UpperNormMagThresholdEditFieldLabel = uilabel(app.GridLayout26);
            app.UpperNormMagThresholdEditFieldLabel.Text = 'Upper Norm Mag Threshold';
            app.UpperNormMagThresholdEditField = uieditfield(app.GridLayout26, 'numeric');
            app.UpperNormMagThresholdEditField.Limits = [0 20];
            app.UpperNormMagThresholdEditField.Placeholder = '8';
            app.UpperNormMagThresholdEditField.Value = 8;
            app.BaselineThresholdEditFieldLabel = uilabel(app.GridLayout26);
            app.BaselineThresholdEditFieldLabel.Text = 'Baseline Threshold';
            app.BaselineThresholdEditField = uieditfield(app.GridLayout26, 'numeric');
            app.BaselineThresholdEditField.Limits = [0 10];
            app.BaselineThresholdEditField.Value = 0.5;
            app.BaselineMinDurationsEditFieldLabel = uilabel(app.GridLayout26);
            app.BaselineMinDurationsEditFieldLabel.Text = 'Baseline  Min  Duration (s)';
            app.BaselineMinDurationsEditField = uieditfield(app.GridLayout26, 'numeric');
            app.BaselineMinDurationsEditField.Limits = [0 100];
            app.BaselineMinDurationsEditField.Value = 5;
            app.EditField_2 = uieditfield(app.GridLayout26, 'numeric');
            app.EditField_2.Limits = [0 7000];
            app.EditField_2.Value = 850;
            app.DurationmsLabel = uilabel(app.GridLayout26);
            app.DurationmsLabel.Text = 'Duration (ms)';
            app.msLabel = uilabel(app.GridLayout26);
            app.msLabel.Text = 'ms';
            app.MinReachCooldownLabel = uilabel(app.GridLayout26);
            app.MinReachCooldownLabel.Text = 'Min. Time B/W Reach';
            app.MinTimeBWReachEditField = uieditfield(app.GridLayout26, 'numeric');
            app.MinTimeBWReachEditField.Limits = [0 10000];
            app.MinTimeBWReachEditField.Value = 1660;
            app.unitsLabel = uilabel(app.GridLayout26);
            app.unitsLabel.Text = 'units';
            app.unitsLabel_2 = uilabel(app.GridLayout26);
            app.unitsLabel_2.Text = 'units';
            app.msLabel_2 = uilabel(app.GridLayout26);
            app.msLabel_2.Text = 'ms';
            app.MaxReachPerturbationEditFieldLabel = uilabel(app.GridLayout26);
            app.MaxReachPerturbationEditFieldLabel.Text = 'Max. Reach Perturbation';
            app.MaxReachPerturbationEditField = uieditfield(app.GridLayout26, 'numeric');
            app.MaxReachPerturbationEditField.Limits = [1 1000];
            app.MaxReachPerturbationEditField.Value = 850;
            app.msLabel_3 = uilabel(app.GridLayout26);
            app.msLabel_3.Text = 'ms';
            app.nansTab = uitab(app.TabGroup2);
            app.nansTab.Title = 'nans';
            app.GridLayout27 = uigridlayout(app.nansTab);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.UIAxes2 = uiaxes(app.GridLayout28);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.GridLayout30 = uigridlayout(app.GridLayout28);
            app.FixMissingValuesPanel = uipanel(app.GridLayout30);
            app.FixMissingValuesPanel.Title = 'Fix Missing Values';
            app.GridLayout33 = uigridlayout(app.FixMissingValuesPanel);
            app.ThresholdFactorEditFieldLabel = uilabel(app.GridLayout33);
            app.ThresholdFactorEditFieldLabel.Text = 'Threshold Factor';
            app.ThresholdFactorEditField = uieditfield(app.GridLayout33, 'numeric');
            app.ThresholdFactorEditField.Limits = [0 Inf];
            app.ThresholdFactorEditField.Value = 2;
            app.InterpolateButton = uibutton(app.GridLayout33, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.ImputationMeanButton = uibutton(app.GridLayout33, 'push');
            app.ImputationMeanButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.ImputationMeanButton.Text = 'Imputation Mean';
            app.tLabel = uilabel(app.GridLayout33);
            app.tLabel.Text = '[t]';
            app.SmoothingSettingsPanel = uipanel(app.GridLayout30);
            app.SmoothingSettingsPanel.Title = 'Smoothing Settings';
            app.GridLayout33_3 = uigridlayout(app.SmoothingSettingsPanel);
            app.smoothwindowmsEditFieldLabel = uilabel(app.GridLayout33_3);
            app.smoothwindowmsEditFieldLabel.Text = 'smooth window (ms)';
            app.smoothwindowmsEditField = uieditfield(app.GridLayout33_3, 'numeric');
            app.smoothwindowmsEditField.Limits = [0 Inf];
            app.SmoothButton = uibutton(app.GridLayout33_3, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.sLabel = uilabel(app.GridLayout33_3);
            app.sLabel.Text = '[s]';
            app.GridLayout33_2 = uigridlayout(app.GridLayout30);
            app.Spinner = uispinner(app.GridLayout33_2);
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Segment_NumberLabel = uilabel(app.GridLayout33_2);
            app.Segment_NumberLabel.Text = 'Segment_Number';
            app.Lamp = uilamp(app.GridLayout33_2);
            app.GridLayout34 = uigridlayout(app.GridLayout30);
            app.RESETButton = uibutton(app.GridLayout34, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.resettooriginaldlcEscLabel = uilabel(app.GridLayout34);
            app.resettooriginaldlcEscLabel.Text = {'reset to original dlc'; '(Esc)'};
            app.GridLayout29 = uigridlayout(app.GridLayout27);
            app.DetectButton = uibutton(app.GridLayout29, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.TextArea_2 = uitextarea(app.GridLayout29);
            app.ListBox_2 = uilistbox(app.GridLayout29);
            app.LogText = uitextarea(app.GridLayout29);
            app.GridLayout31 = uigridlayout(app.GridLayout29);
            app.LoadSegmentsButton = uibutton(app.GridLayout31, 'push');
            app.LoadSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentsButtonPushed, true);
            app.LoadSegmentsButton.Text = 'Load Segments';
            app.earlystartEditFieldLabel = uilabel(app.GridLayout31);
            app.earlystartEditFieldLabel.Text = 'early start';
            app.earlystartEditField = uieditfield(app.GridLayout31, 'numeric');
            app.earlystartEditField.Limits = [0 Inf];
            app.SavePos3DChangesButton = uibutton(app.GridLayout29, 'push');
            app.SavePos3DChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePos3DChangesButtonPushed, true);
            app.SavePos3DChangesButton.Text = 'Save Pos3D Changes';
            app.VelocitySwitch = uiswitch(app.GridLayout29, 'slider');
            app.VelocitySwitch.Items = {'Position', 'Velocity'};
            app.VelocitySwitch.ValueChangedFcn = createCallbackFcn(app, @VelocitySwitchValueChanged, true);
            app.VelocitySwitch.Value = 'Position';
            app.NeuralAnalysisTab = uitab(app.TabGroup);
            app.NeuralAnalysisTab.Title = 'Neural Analysis';
            app.GridLayout40 = uigridlayout(app.NeuralAnalysisTab);
            app.GridLayout41 = uigridlayout(app.GridLayout40);
            app.UIAxes6 = uiaxes(app.GridLayout41);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.GridLayout43 = uigridlayout(app.GridLayout41);
            app.GridLayout44 = uigridlayout(app.GridLayout43);
            app.PlayButton_2 = uibutton(app.GridLayout44, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.DropDown_2 = uidropdown(app.GridLayout44);
            app.DropDown_2.Items = {'jet', 'parula', 'sky', 'gray', 'turbo', 'hsv'};
            app.DropDown_2.Value = 'jet';
            app.DropDown_3 = uidropdown(app.GridLayout44);
            app.DropDown_3.Items = {'Scatter', 'Grid'};
            app.DropDown_3.Value = 'Scatter';
            app.Slider_3 = uislider(app.GridLayout43);
            app.GridLayout50 = uigridlayout(app.GridLayout43);
            app.GridLayout49 = uigridlayout(app.GridLayout50);
            app.SamplingRateEditFieldLabel = uilabel(app.GridLayout49);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.GridLayout49, 'numeric');
            app.SamplingRateEditField.Limits = [0 Inf];
            app.SamplingRateEditField.RoundFractionalValues = 'on';
            app.SamplingRateEditField.ValueDisplayFormat = '%i';
            app.SamplingRateEditField.Value = 30000;
            app.FrameStepEditFieldLabel = uilabel(app.GridLayout49);
            app.FrameStepEditFieldLabel.Text = 'Frame Step';
            app.FrameStepEditField = uieditfield(app.GridLayout49, 'numeric');
            app.FrameStepEditField.Limits = [1 30000];
            app.FrameStepEditField.RoundFractionalValues = 'on';
            app.FrameStepEditField.ValueDisplayFormat = '%i';
            app.FrameStepEditField.Value = 1;
            app.MarkerSizeEditFieldLabel = uilabel(app.GridLayout49);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.GridLayout49, 'numeric');
            app.MarkerSizeEditField.Limits = [1 500];
            app.MarkerSizeEditField.RoundFractionalValues = 'on';
            app.MarkerSizeEditField.Value = 150;
            app.HzLabel = uilabel(app.GridLayout49);
            app.HzLabel.Text = 'Hz';
            app.howoftentoplotLabel = uilabel(app.GridLayout49);
            app.howoftentoplotLabel.Text = 'how often to plot';
            app.scatterplotmarkersizeLabel = uilabel(app.GridLayout49);
            app.scatterplotmarkersizeLabel.Text = 'scatter plot marker size';
            app.IdxStepEditFieldLabel = uilabel(app.GridLayout49);
            app.IdxStepEditFieldLabel.Enable = 'off';
            app.IdxStepEditFieldLabel.Text = 'Idx Step';
            app.IdxStepEditField = uieditfield(app.GridLayout49, 'numeric');
            app.IdxStepEditField.Limits = [1 1000];
            app.IdxStepEditField.RoundFractionalValues = 'on';
            app.IdxStepEditField.ValueDisplayFormat = '%i';
            app.IdxStepEditField.Enable = 'off';
            app.IdxStepEditField.Value = 1;
            app.stepsizetoiteratebyLabel = uilabel(app.GridLayout49);
            app.stepsizetoiteratebyLabel.Text = 'step size to iterate by';
            app.GridResolutionEditFieldLabel = uilabel(app.GridLayout49);
            app.GridResolutionEditFieldLabel.Text = 'Grid Resolution';
            app.GridResolutionEditField = uieditfield(app.GridLayout49, 'numeric');
            app.GridResolutionEditField.Value = 100;
            app.mustbechangedpriortoSHOWtotakeeffectLabel = uilabel(app.GridLayout49);
            app.mustbechangedpriortoSHOWtotakeeffectLabel.Text = 'must be changed prior to SHOW to take effect';
            app.GridLayout51 = uigridlayout(app.GridLayout50);
            app.TruedurationsecsLabel = uilabel(app.GridLayout51);
            app.TruedurationsecsLabel.Text = 'True duration (secs)';
            app.Label_2 = uilabel(app.GridLayout51);
            app.Label_2.Text = '123';
            app.byPCBButtonGroup = uibuttongroup(app.GridLayout51);
            app.byPCBButtonGroup.Title = 'by PCB';
            app.Arm1Button = uiradiobutton(app.byPCBButtonGroup);
            app.Arm1Button.Text = 'Arm 1';
            app.Arm2Button = uiradiobutton(app.byPCBButtonGroup);
            app.Arm2Button.Text = 'Arm 2';
            app.Arm3Button = uiradiobutton(app.byPCBButtonGroup);
            app.Arm3Button.Text = 'Arm 3';
            app.Arm4Button = uiradiobutton(app.byPCBButtonGroup);
            app.Arm4Button.Text = 'Arm 4';
            app.AllButton = uiradiobutton(app.byPCBButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.GridLayout42 = uigridlayout(app.GridLayout40);
            app.LoadNeuralButton = uibutton(app.GridLayout42, 'push');
            app.LoadNeuralButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNeuralButtonPushed, true);
            app.LoadNeuralButton.Text = 'Load Neural';
            app.TextArea_3 = uitextarea(app.GridLayout42);
            app.SelectButton = uibutton(app.GridLayout42, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.FilecontentsPanel = uipanel(app.GridLayout42);
            app.FilecontentsPanel.Title = 'File contents';
            app.GridLayout47 = uigridlayout(app.FilecontentsPanel);
            app.ListBox_3 = uilistbox(app.GridLayout47);
            app.ListBox_3.Items = {};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.DoubleClickedFcn = createCallbackFcn(app, @ListBox_3DoubleClicked, true);
            app.ListBox_3.Value = {};
            app.GridLayout48 = uigridlayout(app.GridLayout42);
            app.SHOWButton = uibutton(app.GridLayout48, 'push');
            app.SHOWButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWButtonPushed, true);
            app.SHOWButton.Text = 'SHOW';
            app.ElectrodePositionPanel = uipanel(app.GridLayout42);
            app.ElectrodePositionPanel.Title = 'Electrode Position';
            app.GridLayout46 = uigridlayout(app.ElectrodePositionPanel);
            app.LoaduECoGSchemaButton = uibutton(app.GridLayout46, 'push');
            app.LoaduECoGSchemaButton.Text = {'Load uECoG '; 'Schema'};
            app.LoadGooseButton = uibutton(app.GridLayout46, 'push');
            app.LoadGooseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGooseButtonPushed, true);
            app.LoadGooseButton.Text = {'Load'; 'Goose'};
            app.uEcog244electrodesLabel = uilabel(app.GridLayout46);
            app.uEcog244electrodesLabel.Text = 'uEcog (244 electrodes)';
            app.SegmentsTab = uitab(app.TabGroup);
            app.SegmentsTab.Title = 'Segments';
            app.GridLayout37 = uigridlayout(app.SegmentsTab);
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.Panel = uipanel(app.GridLayout38);
            app.Panel.Title = 'Panel';
            app.GridLayout39 = uigridlayout(app.Panel);
            app.UIAxes3 = uiaxes(app.GridLayout39);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Panel2 = uipanel(app.GridLayout38);
            app.Panel2.Title = 'Panel2';
            app.GridLayout39_2 = uigridlayout(app.Panel2);
            app.UIAxes4 = uiaxes(app.GridLayout39_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Panel3 = uipanel(app.GridLayout38);
            app.Panel3.Title = 'Panel3';
            app.GridLayout39_3 = uigridlayout(app.Panel3);
            app.UIAxes5 = uiaxes(app.GridLayout39_3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout36 = uigridlayout(app.AboutTab);
            app.Image3 = uiimage(app.GridLayout36);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'created_by_alex.png');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'simia_title.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.GotosegmentMenu = uimenu(app.ContextMenu);
            app.GotosegmentMenu.Enable = 'off';
            app.GotosegmentMenu.Text = 'Go to segment...';
            app.startMenu = uimenu(app.GotosegmentMenu);
            app.startMenu.MenuSelectedFcn = createCallbackFcn(app, @startMenuSelected, true);
            app.startMenu.Text = 'start';
            app.endMenu = uimenu(app.GotosegmentMenu);
            app.endMenu.MenuSelectedFcn = createCallbackFcn(app, @endMenuSelected, true);
            app.endMenu.Text = 'end';
            app.playsegmentMenu = uimenu(app.GotosegmentMenu);
            app.playsegmentMenu.MenuSelectedFcn = createCallbackFcn(app, @playsegmentMenuSelected, true);
            app.playsegmentMenu.Text = 'play segment';
            app.DeletesegmentMenu = uimenu(app.ContextMenu);
            app.DeletesegmentMenu.MenuSelectedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.DeletesegmentMenu.Text = 'Delete segment';
            app.AddexistingsegmentsMenu = uimenu(app.ContextMenu);
            app.AddexistingsegmentsMenu.MenuSelectedFcn = createCallbackFcn(app, @AddexistingsegmentsMenuSelected, true);
            app.AddexistingsegmentsMenu.Text = 'Add existing segments';
            app.DeleteallMenu = uimenu(app.ContextMenu);
            app.DeleteallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteallMenuSelected, true);
            app.DeleteallMenu.Text = 'Delete all';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Strada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end